classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GuimtarUIFigure       matlab.ui.Figure
        ChooseyourtuningMenu  matlab.ui.container.Menu
        StandardEMenu         matlab.ui.container.Menu
        DropDMenu             matlab.ui.container.Menu
        StandardDMenu         matlab.ui.container.Menu
        DropCMenu             matlab.ui.container.Menu
        stringsBStandardMenu  matlab.ui.container.Menu
        Image                 matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        Image4                matlab.ui.control.Image
        Image5                matlab.ui.control.Image
        Image6                matlab.ui.control.Image
        Image7                matlab.ui.control.Image
        Button                matlab.ui.control.Button
        Button2               matlab.ui.control.Button
        Button3               matlab.ui.control.Button
        Button4               matlab.ui.control.Button
        Button5               matlab.ui.control.Button
        Button6               matlab.ui.control.Button
        Button7               matlab.ui.control.Button
        Image8                matlab.ui.control.Image
        Gauge                 matlab.ui.control.SemicircularGauge
        PitchLabel            matlab.ui.control.Label
    end

    
    properties (Access = public)
        Frequencies = []
        value = 0% numer stroju
    end
    
    methods (Access = private)
        
        function record(app, correct_tune, actual_tune)
            tune_rounded = round(correct_tune)
            disp(tune_rounded)
            app.Gauge.Limits = [tune_rounded-10, tune_rounded+10];
            app.PitchLabel.Text = actual_tune + " Hz";
            if (actual_tune < correct_tune+0.75 && actual_tune > correct_tune-0.75)
                    set(app.PitchLabel ,'FontColor', [0 1 0])
            else
                    set(app.PitchLabel ,'FontColor', [1 0 0])
            end
        end
        
        function chooseString(app)
            while true
                recObj = audiorecorder(44100, 16, 1);
                get(recObj);
                recordblocking(recObj, 0.5);
                myRecording = getaudiodata(recObj);
                f0 = pitch(myRecording,44100);
                actual_tune = mode(f0); 
                if(actual_tune<400 && actual_tune>261.63)
                    record(app, app.Frequencies(1,app.value), actual_tune)
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image3
        function tunerOnClick(app, event)
            app.Gauge.Visible = "on";
            app.PitchLabel.Visible = "on";
            load('frequencies',"-mat","Frequencies")
            app.Frequencies = Frequencies;
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
            app.ChooseyourtuningMenu.Visible = 'on';
        end

        % Menu selected function: StandardEMenu
        function standardE(app, event)
            app.value = 1;
            chooseString(app)
            app.StandardEMenu.Checked = 'on';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "E4 - " + num2str(app.Frequencies(1,1)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "B3 - " + num2str(app.Frequencies(2,1))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "G3 - " + num2str(app.Frequencies(3,1))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "D3 - " + num2str(app.Frequencies(4,1))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "A2 - " + num2str(app.Frequencies(5,1))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "E2 - " + num2str(app.Frequencies(6,1))+ " Hz";
            app.Button7.Visible = 'off';            
        end

        % Menu selected function: DropDMenu
        function dropD(app, event)
            app.value = 2;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'on';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "E4 - " + num2str(app.Frequencies(1,2)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "B3 - " + num2str(app.Frequencies(2,2))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "G3 - " + num2str(app.Frequencies(3,2))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "D3 - " + num2str(app.Frequencies(4,2))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "A2 - " + num2str(app.Frequencies(5,2))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "D2 - " + num2str(app.Frequencies(6,2))+ " Hz"; 
            app.Button7.Visible = 'off';
        end

        % Menu selected function: StandardDMenu
        function standardD(app, event)
            app.value = 3;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'on';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "D4 - " + num2str(app.Frequencies(1,3)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "A3 - " + num2str(app.Frequencies(2,3))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "F3 - " + num2str(app.Frequencies(3,3))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "C3 - " + num2str(app.Frequencies(4,3))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "G2 - " + num2str(app.Frequencies(5,3))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "D2 - " + num2str(app.Frequencies(6,3))+ " Hz";
            app.Button7.Visible = 'off';
        end

        % Menu selected function: DropCMenu
        function dropC(app, event)
            app.value = 4;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'on';
            app.stringsBStandardMenu.Checked = 'off';
            app.Button.Visible = 'on';
            app.Button.Text = "D4 - " + num2str(app.Frequencies(1,4)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "A3 - " + num2str(app.Frequencies(2,4))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "F3 - " + num2str(app.Frequencies(3,4))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "C3 - " + num2str(app.Frequencies(4,4))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "G2 - " + num2str(app.Frequencies(5,4))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "C2 - " + num2str(app.Frequencies(6,4))+ " Hz";
            app.Button7.Visible = 'off';
        end

        % Menu selected function: stringsBStandardMenu
        function bSevenStandard(app, event)
            app.value = 5;
            app.StandardEMenu.Checked = 'off';
            app.DropDMenu.Checked = 'off';
            app.StandardDMenu.Checked = 'off';
            app.DropCMenu.Checked = 'off';
            app.stringsBStandardMenu.Checked = 'on';
            app.Button.Visible = 'on';
            app.Button.Text = "E4 - " + num2str(app.Frequencies(1,5)) + " Hz";
            app.Button2.Visible = 'on';
            app.Button2.Text = "B3 - " + num2str(app.Frequencies(2,5))+ " Hz";
            app.Button3.Visible = 'on';
            app.Button3.Text = "G3 - " + num2str(app.Frequencies(3,5))+ " Hz";
            app.Button4.Visible = 'on';
            app.Button4.Text = "D3 - " + num2str(app.Frequencies(4,5))+ " Hz";
            app.Button5.Visible = 'on';
            app.Button5.Text = "A2 - " + num2str(app.Frequencies(5,5))+ " Hz";
            app.Button6.Visible = 'on';
            app.Button6.Text = "E2 - " + num2str(app.Frequencies(6,5))+ " Hz";  
            app.Button7.Visible = 'on';
            app.Button7.Text = "B1 - " + num2str(app.Frequencies(7,5))+ " Hz"; 
        end

        % Image clicked function: Image4
        function metronomeOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
        end

        % Image clicked function: Image5
        function soundRecorderOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
        end

        % Image clicked function: Image6
        function tabsCollectionOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
        end

        % Image clicked function: Image7
        function myEquipmentOnClick(app, event)
            app.Image3.delete
            app.Image4.delete
            app.Image5.delete
            app.Image6.delete
            app.Image7.delete
        end

        % Image clicked function: Image2, Image8
        function home(app, event)
            app.ChooseyourtuningMenu.Visible = 'off';
            % Create Image
            app.Image = uiimage(app.GuimtarUIFigure);
            app.Image.ScaleMethod = 'fill';
            app.Image.Position = [1 -103 640 650];
            app.Image.ImageSource = 'azure-linear-gradient-magenta-1920x1080-c2-04273d-33043d-a-60-f-14.jpg';

            % Create Image2
            app.Image2 = uiimage(app.GuimtarUIFigure);
            app.Image2.Position = [225 377 191 170];
            app.Image2.ImageSource = 'logo.png';

            % Create Image3
            app.Image3 = uiimage(app.GuimtarUIFigure);
            app.Image3.ScaleMethod = 'none';
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @tunerOnClick, true);
            app.Image3.Position = [96 313 450 100];
            app.Image3.ImageSource = 'button_guitar-tuner.png';

            % Create Image4
            app.Image4 = uiimage(app.GuimtarUIFigure);
            app.Image4.ScaleMethod = 'none';
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @metronomeOnClick, true);
            app.Image4.Position = [120 237 401 100];
            app.Image4.ImageSource = 'button_metronome.png';

            % Create Image5
            app.Image5 = uiimage(app.GuimtarUIFigure);
            app.Image5.ScaleMethod = 'none';
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @soundRecorderOnClick, true);
            app.Image5.Position = [120 162 401 100];
            app.Image5.ImageSource = 'button_sound-recorder.png';

            % Create Image6
            app.Image6 = uiimage(app.GuimtarUIFigure);
            app.Image6.ScaleMethod = 'none';
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @tabsCollectionOnClick, true);
            app.Image6.Position = [120 85 401 100];
            app.Image6.ImageSource = 'button_tabs-collection.png';

            % Create Image7
            app.Image7 = uiimage(app.GuimtarUIFigure);
            app.Image7.ScaleMethod = 'none';
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @myEquipmentOnClick, true);
            app.Image7.Position = [120 9 401 100];
            app.Image7.ImageSource = 'button_my-equipment.png';

            % Create Button
            app.Button = uibutton(app.GuimtarUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @firstString, true);
            app.Button.Visible = 'off';
            app.Button.Position = [120 366 100 22];

            % Create Button2
            app.Button2 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @secondString, true);
            app.Button2.Visible = 'off';
            app.Button2.Position = [271 366 100 22];
            app.Button2.Text = 'Button2';

            % Create Button3
            app.Button3 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @thirdString, true);
            app.Button3.Visible = 'off';
            app.Button3.Position = [421 366 100 22];
            app.Button3.Text = 'Button3';

            % Create Button4
            app.Button4 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @fourthString, true);
            app.Button4.Visible = 'off';
            app.Button4.Position = [120 292 100 22];
            app.Button4.Text = 'Button4';

            % Create Button5
            app.Button5 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @fifthString, true);
            app.Button5.Visible = 'off';
            app.Button5.Position = [271 292 100 22];
            app.Button5.Text = 'Button5';

            % Create Button6
            app.Button6 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @sixthString, true);
            app.Button6.Visible = 'off';
            app.Button6.Position = [421 292 100 22];
            app.Button6.Text = 'Button6';

            % Create Button7
            app.Button7 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @seventhString, true);
            app.Button7.Visible = 'off';
            app.Button7.Position = [120 211 100 22];
            app.Button7.Text = 'Button7';

            % Create Image8
            app.Image8 = uiimage(app.GuimtarUIFigure);
            app.Image8.ScaleMethod = 'fill';
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @home, true);
            app.Image8.Position = [541 162 100 100];
            app.Image8.ImageSource = 'home.png';
            
            % Create Gauge
            app.Gauge = uigauge(app.GuimtarUIFigure, 'semicircular');
            app.Gauge.Visible = 'off';
            app.Gauge.BackgroundColor = [0.1373 0.1373 0.6314];
            app.Gauge.FontName = 'Verdana';
            app.Gauge.FontColor = [0.7176 0.2745 1];
            app.Gauge.Position = [191 64 260 141];

            % Create PitchLabel
            app.PitchLabel = uilabel(app.GuimtarUIFigure);
            app.PitchLabel.HorizontalAlignment = 'center';
            app.PitchLabel.FontSize = 20;
            app.PitchLabel.FontColor = [0.7176 0.2745 1];
            app.PitchLabel.Visible = 'off';
            app.PitchLabel.Position = [271 16 116 49];
            app.PitchLabel.Text = 'Pitch';
            % Show the figure after all components are created
            app.GuimtarUIFigure.Visible = 'on';
        end

        % Button pushed function: Button2
        function secondString(app, event)
            record(app, app.Frequencies(2,app.value))
        end

        % Button pushed function: Button3
        function thirdString(app, event)
            record(app, app.Frequencies(3,app.value))
        end

        % Button pushed function: Button4
        function fourthString(app, event)
            record(app, app.Frequencies(4,app.value))
        end

        % Button pushed function: Button5
        function fifthString(app, event)
            record(app, app.Frequencies(5,app.value))
        end

        % Button pushed function: Button6
        function sixthString(app, event)
            record(app, app.Frequencies(6,app.value))
        end

        % Button pushed function: Button7
        function seventhString(app, event)
            record(app, app.Frequencies(7,app.value))
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GuimtarUIFigure = uifigure('Visible', 'off');
            app.GuimtarUIFigure.Name = 'Guimtar';
            app.ChooseyourtuningMenu = uimenu(app.GuimtarUIFigure);
            app.ChooseyourtuningMenu.Visible = 'off';
            app.ChooseyourtuningMenu.Text = 'Choose your tuning';
            app.StandardEMenu = uimenu(app.ChooseyourtuningMenu);
            app.StandardEMenu.MenuSelectedFcn = createCallbackFcn(app, @standardE, true);
            app.StandardEMenu.Text = 'Standard E';
            app.DropDMenu = uimenu(app.ChooseyourtuningMenu);
            app.DropDMenu.MenuSelectedFcn = createCallbackFcn(app, @dropD, true);
            app.DropDMenu.Text = 'Drop D';
            app.StandardDMenu = uimenu(app.ChooseyourtuningMenu);
            app.StandardDMenu.MenuSelectedFcn = createCallbackFcn(app, @standardD, true);
            app.StandardDMenu.Text = 'Standard D';
            app.DropCMenu = uimenu(app.ChooseyourtuningMenu);
            app.DropCMenu.MenuSelectedFcn = createCallbackFcn(app, @dropC, true);
            app.DropCMenu.Text = 'Drop C';
            app.stringsBStandardMenu = uimenu(app.ChooseyourtuningMenu);
            app.stringsBStandardMenu.MenuSelectedFcn = createCallbackFcn(app, @bSevenStandard, true);
            app.stringsBStandardMenu.Text = '7 strings B Standard';
            app.Image = uiimage(app.GuimtarUIFigure);
            app.Image.ImageSource = 'azure-linear-gradient-magenta-1920x1080-c2-04273d-33043d-a-60-f-14.jpg';
            app.Image2 = uiimage(app.GuimtarUIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @home, true);
            app.Image2.ImageSource = 'logo.png';
            app.Image3 = uiimage(app.GuimtarUIFigure);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @tunerOnClick, true);
            app.Image3.ImageSource = 'button_guitar-tuner.png';
            app.Image4 = uiimage(app.GuimtarUIFigure);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @metronomeOnClick, true);
            app.Image4.ImageSource = 'button_metronome.png';
            app.Image5 = uiimage(app.GuimtarUIFigure);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @soundRecorderOnClick, true);
            app.Image5.ImageSource = 'button_sound-recorder.png';
            app.Image6 = uiimage(app.GuimtarUIFigure);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @tabsCollectionOnClick, true);
            app.Image6.ImageSource = 'button_tabs-collection.png';
            app.Image7 = uiimage(app.GuimtarUIFigure);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @myEquipmentOnClick, true);
            app.Image7.ImageSource = 'button_my-equipment.png';
            app.Button = uibutton(app.GuimtarUIFigure, 'push');
            app.Button.Visible = 'off';
            app.Button2 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @secondString, true);
            app.Button2.Visible = 'off';
            app.Button2.Text = 'Button2';
            app.Button3 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @thirdString, true);
            app.Button3.Visible = 'off';
            app.Button3.Text = 'Button3';
            app.Button4 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @fourthString, true);
            app.Button4.Visible = 'off';
            app.Button4.Text = 'Button4';
            app.Button5 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @fifthString, true);
            app.Button5.Visible = 'off';
            app.Button5.Text = 'Button5';
            app.Button6 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @sixthString, true);
            app.Button6.Visible = 'off';
            app.Button6.Text = 'Button6';
            app.Button7 = uibutton(app.GuimtarUIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @seventhString, true);
            app.Button7.Visible = 'off';
            app.Button7.Text = 'Button7';
            app.Image8 = uiimage(app.GuimtarUIFigure);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @home, true);
            app.Image8.ImageSource = 'home.png';
            app.Gauge = uigauge(app.GuimtarUIFigure, 'semicircular');
            app.Gauge.Visible = 'off';
            app.PitchLabel = uilabel(app.GuimtarUIFigure);
            app.PitchLabel.Visible = 'off';
            app.PitchLabel.Text = 'Pitch';
            % Show the figure after all components are created
            app.GuimtarUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GuimtarUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GuimtarUIFigure)
        end
    end
end